classdef CatchBall < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Knob_azLabel           matlab.ui.control.Label
        Knob_az                matlab.ui.control.Knob
        SolveButton            matlab.ui.control.Button
        AnimationButton        matlab.ui.control.Button
        velocity_xSliderLabel  matlab.ui.control.Label
        velocity_xSlider       matlab.ui.control.Slider
        velocity_ySliderLabel  matlab.ui.control.Label
        velocity_ySlider       matlab.ui.control.Slider
        velocity_zSliderLabel  matlab.ui.control.Label
        velocity_zSlider       matlab.ui.control.Slider
        Label                  matlab.ui.control.Label
        TextArea               matlab.ui.control.TextArea
        ClearButton            matlab.ui.control.Button
    end


    methods (Access = private)
        
        function updatetrace(app)
            global velx vely velz g;
            global time_step;
            global fig trace_ball;
            global time_vec;
            ball_initial_pos=[6,0,1]; 
            velx=app.velocity_xSlider.Value;
            vely=app.velocity_ySlider.Value;
            velz=app.velocity_zSlider.Value;
            endtime=(velz+sqrt(velz*velz+2*g*(1+ball_initial_pos(3))))/g;%到地板（z=-1）停止
            time_vec=0:time_step:endtime;
            time_vec=transpose(time_vec);
            ball_x=velx*time_vec+ball_initial_pos(1);
            ball_y=vely*time_vec+ball_initial_pos(2);
            ball_z=velz*time_vec-g/2*time_vec.^2+ball_initial_pos(3);
            set(trace_ball,'xdata',ball_x,'ydata',ball_y,'zdata',ball_z);
            drawnow;         
        end
        function data = catchball(app,ball_x,ball_y,ball_z,time_vec)
            global robot;
            global time_step;
            N=length(time_vec);
            q_init=[0 0 0 0 0 0];
            iscatch=-1;
            for i=1:1:N
            targ_p=[ball_x(i);ball_y(i);ball_z(i)];
            %首先根据工作空间排除达不到的点↓
            if not((targ_p(1)>-2) & (targ_p(1)<2) & (targ_p(2)>-2) & (targ_p(2)<2) & (targ_p(3)>-2) & (targ_p(3)<3))
                continue;
            end
            %排除达不到的点↓
            p=[0 0 -1 0;0 -1 0 0;-1 0 0 0;0 0 0 1];
            p(1:3,4)=targ_p;
            q_targ=ikine(robot,p);
            if(isempty(q_targ))
                continue;
            end
            %排除时间层面上来不及达到的点↓
            time=0.8;
            qd_limit=2.2;
            robot_time_vec=0:time_step:time;
            [q_targ_t ,qd_t, qdd_t]=jtraj(q_init,q_targ,robot_time_vec);

            while(max(abs(qd_t),[],'all')>qd_limit)
                time=time*max(abs(qd_t),[],'all')/qd_limit+time_step;
                robot_time_vec=0:time_step:time;
                [q_targ_t ,qd_t, qdd_t]=jtraj(q_init,q_targ,robot_time_vec);
            end
            %可以达到的点↓
            if time<=time_vec(i)
                iscatch=1;
                break;
            end
            end
            %输出结果↓
            global omega;
            omega=zeros(i,6,1);
            if iscatch==-1
             data=[];
            else
                n=length(robot_time_vec);
                data=zeros(i,7,1);
                data(:,1)=time_vec(1:i);
                data(i-n+1:i,2:7)=q_targ_t;
                omega(i-n+1:i,:)=qd_t;
            end
            
          end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            th(1) = 0; d(1) = 0.2;   a(1) = -0.220;  alp(1) = pi/2;
            th(2) = 0; d(2) = 0;     a(2) = -1.069; alp(2) = 0;   
            th(3) = 0; d(3) = 0.1;   a(3) = 0;      alp(3) = -pi/2;
            th(4) = 0; d(4) = 1.04;  a(4) = 0;      alp(4) = pi/2;
            th(5) = 0; d(5) = 0;     a(5) = 0;      alp(5) = pi/2;
            th(6) = 0; d(6) = 0.495; a(6) = 0;      alp(6) = pi;
            L(1) = Link([th(1), d(1), a(1), alp(1), 0], 'revolute');
            L(2) = Link([th(2), d(2), a(2), alp(2), 0], 'revolute');L(2).offset=-pi/2;
            L(3) = Link([th(3), d(3), a(3), alp(3), 0], 'revolute');
            L(4) = Link([th(4), d(4), a(4), alp(4), 0], 'revolute');L(4).offset=pi;
            L(5) = Link([th(5), d(5), a(5), alp(5), 0], 'revolute');L(5).offset=-pi;
            L(6) = Link([th(6), d(6), a(6), alp(6), 0], 'revolute');
            global robot
            robot = SerialLink(L); 
            robot.name='B09';
            global v w;
            global velx vely velz g;
            global time_step;
            global time_vec;
            ball_initial_pos=[6,0,1];
            velx=-1;
            vely=-1/6;
            velz=1;
            g=1/3;
            
            time_step=0.05;
            endtime=(velz+sqrt(velz*velz+2*g*(1+ball_initial_pos(3))))/g;%到地板（z=-1）停止
            time_vec=0:time_step:endtime;
            time_vec=transpose(time_vec);
            ball_x=velx*time_vec+ball_initial_pos(1);
            ball_y=vely*time_vec+ball_initial_pos(2);
            ball_z=velz*time_vec-g/2*time_vec.^2+ball_initial_pos(3);
            
            radius=0.1;
            [ball_x_mesh,ball_y_mesh,ball_z_mesh]=sphere;
            ball_x_mesh=ball_x_mesh*radius;
            ball_y_mesh=ball_y_mesh*radius;
            ball_z_mesh=ball_z_mesh*radius;
            
            v=[70 30];
            w=[-3 7 -4 4 -1 4];
            range_w=[w(2)-w(1),w(4)-w(3),w(6)-w(5)];
            global fig;
            fig=figure(2);
            fig.Position=[100 100 1400 1200];
            axis(w);
            pbaspect(range_w);
            robot.plot3d([0 0 0 0 0 0],'tilesize',0.5,'workspace',w,'path','.\stl','nowrist','view',v,'perspective');
            hold on;
            global trace_ball;
            trace_ball=plot3(ball_x,ball_y,ball_z,'LineStyle','--','LineWidth',2);
            ball=surf(ball_x_mesh+ball_initial_pos(1),ball_y_mesh+ball_initial_pos(2),ball_z_mesh+ball_initial_pos(3),'EdgeColor','none');
            fig=figure(3);
            fig.Position=[1500 100 500 500];
            fig=figure(2);
        end

        % Value changed function: Knob_az
        function Knob_azValueChanged(app, event)

            global velx vely velz g;
            global time_step;
            global fig trace_ball;
            global time_vec;
            global ball_x ball_y ball_z;
            global ball_initial_pos;
            global fig v w;
            global robot;
            value = app.Knob_az.Value;
            v(1)=value;
            view(v);
        end

        % Callback function
        function Knob_elValueChanged(app, event)
      
        end

        % Value changed function: velocity_xSlider
        function velocity_xSliderValueChanged(app, event)
            value = app.velocity_xSlider.Value;
            updatetrace(app);
            
        end

        % Value changed function: velocity_ySlider
        function velocity_ySliderValueChanged(app, event)
            value = app.velocity_ySlider.Value;
            updatetrace(app);
        end

        % Value changed function: velocity_zSlider
        function velocity_zSliderValueChanged(app, event)
            value = app.velocity_zSlider.Value;
            updatetrace(app);
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            app.TextArea.Value="求解中";
            global velx vely velz g;
            global time_step;
            global fig trace_ball;
            global time_vec;
            global ball_x ball_y ball_z;
            global ball_initial_pos;
            ball_initial_pos=[6,0,1]; 
            endtime=(velz+sqrt(velz*velz+2*g*(1+ball_initial_pos(3))))/g;%到地板（z=-1）停止
            time_vec=0:time_step:endtime;
            time_vec=transpose(time_vec);
            ball_x=velx*time_vec+ball_initial_pos(1);
            ball_y=vely*time_vec+ball_initial_pos(2);
            ball_z=velz*time_vec-g/2*time_vec.^2+ball_initial_pos(3);
            global data;
            global q_targ_t;
            
            data=catchball(app,ball_x,ball_y,ball_z,time_vec);
            if isempty(data)
              q_targ_t=zeros(length(time_vec),6,1);
              app.TextArea.Value="无法接中球";
            else
              app.TextArea.Value="可以接中球";

              q_targ_t=data(:,2:7);
              time_vec=data(:,1);  
            end
        end

        % Button pushed function: AnimationButton
        function AnimationButtonPushed(app, event)
            global data;
            global q_targ_t;
            global time_vec;
            global fig;
            global ball_x ball_y ball_z;
            global ball_initial_pos;
            global robot;
            global omega;

            i=1;
            fig=figure(3);         
            qd1=plot(time_vec(1:i),omega(1:i,1));
            axis([0 time_vec(length(time_vec)) min(omega,[],'all') max(omega,[],'all')]);
            hold on;
            qd2=plot(time_vec(1:i),omega(1:i,2));
            qd3=plot(time_vec(1:i),omega(1:i,3));
            qd4=plot(time_vec(1:i),omega(1:i,4));
            qd5=plot(time_vec(1:i),omega(1:i,5));
            qd6=plot(time_vec(1:i),omega(1:i,6));
            title('各关节角速度');
            xlabel('Time/s');
            ylabel('角速度');
            legend('关节1','关节2','关节3','关节4','关节5','关节6');
            grid on;
            fig=figure(2);
            
            radius=0.1;
            [ball_x_mesh,ball_y_mesh,ball_z_mesh]=sphere;
            ball_x_mesh=ball_x_mesh*radius;
            ball_y_mesh=ball_y_mesh*radius;
            ball_z_mesh=ball_z_mesh*radius;
            clf(fig);
            global v w;
            range_w=[w(2)-w(1),w(4)-w(3),w(6)-w(5)];
            for i=1:1:length(time_vec)
             ball=surf(ball_x_mesh+ball_x(i),ball_y_mesh+ball_y(i),ball_z_mesh+ball_z(i),'EdgeColor','none'); 
             hold on;
             shooter=plot3(ball_initial_pos(1),ball_initial_pos(2),ball_initial_pos(3),'Marker','o','MarkerSize',15,'LineWidth',3);
             trace_ball=plot3(ball_x(1:i),ball_y(1:i),ball_z(1:i),'LineStyle','--','LineWidth',2);
             linez_ball=plot3([ball_x(i),ball_x(i)],[ball_y(i),ball_y(i)],[-1,ball_z(i)],'LineStyle','--');
             liney_ball=plot3([ball_initial_pos(1),ball_initial_pos(1)],[ball_initial_pos(2),ball_y(i)],[-1,-1],'LineStyle','--');
             linex_ball=plot3([ball_initial_pos(1),ball_x(i)],[ball_y(i),ball_y(i)],[-1,-1],'LineStyle','--');
             axis(w);
             pbaspect(range_w);
             robot.plot3d(q_targ_t(i,:),'tilesize',0.5,'workspace',w,'path','.\stl','nowrist','view',v,'perspective');
             
             fig=figure(3);
             
             set(qd1,'xdata',time_vec(1:i),'ydata',omega(1:i,1));
             set(qd2,'xdata',time_vec(1:i),'ydata',omega(1:i,2));
             set(qd3,'xdata',time_vec(1:i),'ydata',omega(1:i,3));
             set(qd4,'xdata',time_vec(1:i),'ydata',omega(1:i,4));
             set(qd5,'xdata',time_vec(1:i),'ydata',omega(1:i,5));
             set(qd6,'xdata',time_vec(1:i),'ydata',omega(1:i,6));
             
             fig=figure(2);
             
             
             if i<length(time_vec)
             clf(fig)
             end
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            global robot
            global v w;
            global velx vely velz g;
            global time_step;
            global time_vec;
            global fig;
            global trace_ball;
            global ball_x ball_y ball_z;
            ball_initial_pos=[6,0,1];
            velx=app.velocity_xSlider.Value;
            vely=app.velocity_ySlider.Value;
            velz=app.velocity_zSlider.Value;
            app.TextArea.Value="未求解";
            
            endtime=(velz+sqrt(velz*velz+2*g*(1+ball_initial_pos(3))))/g;%到地板（z=-1）停止
            time_vec=0:time_step:endtime;
            time_vec=transpose(time_vec);
            ball_x=velx*time_vec+ball_initial_pos(1);
            ball_y=vely*time_vec+ball_initial_pos(2);
            ball_z=velz*time_vec-g/2*time_vec.^2+ball_initial_pos(3);
            
            radius=0.1;
            [ball_x_mesh,ball_y_mesh,ball_z_mesh]=sphere;
            ball_x_mesh=ball_x_mesh*radius;
            ball_y_mesh=ball_y_mesh*radius;
            ball_z_mesh=ball_z_mesh*radius;
            clf(fig);
            fig=figure(3);
            clf(fig);
            fig=figure(2);
            robot.plot3d([0 0 0 0 0 0],'tilesize',0.5,'workspace',w,'path','.\stl','nowrist','view',v,'perspective');
            hold on;        
            trace_ball=plot3(ball_x,ball_y,ball_z,'LineStyle','--','LineWidth',2);
            ball=surf(ball_x_mesh+ball_initial_pos(1),ball_y_mesh+ball_initial_pos(2),ball_z_mesh+ball_initial_pos(3),'EdgeColor','none');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Knob_azLabel = uilabel(app.UIFigure);
            app.Knob_azLabel.Text = 'Knob_az';
            app.Knob_az = uiknob(app.UIFigure, 'continuous');
            app.Knob_az.Limits = [-180 180];
            app.Knob_az.ValueChangedFcn = createCallbackFcn(app, @Knob_azValueChanged, true);
            app.Knob_az.Value = 70;
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.AnimationButton = uibutton(app.UIFigure, 'push');
            app.AnimationButton.ButtonPushedFcn = createCallbackFcn(app, @AnimationButtonPushed, true);
            app.AnimationButton.Text = 'Animation';
            app.velocity_xSliderLabel = uilabel(app.UIFigure);
            app.velocity_xSliderLabel.Text = 'velocity_x';
            app.velocity_xSlider = uislider(app.UIFigure);
            app.velocity_xSlider.Limits = [-5 0];
            app.velocity_xSlider.ValueChangedFcn = createCallbackFcn(app, @velocity_xSliderValueChanged, true);
            app.velocity_xSlider.Value = -1;
            app.velocity_ySliderLabel = uilabel(app.UIFigure);
            app.velocity_ySliderLabel.Text = 'velocity_y';
            app.velocity_ySlider = uislider(app.UIFigure);
            app.velocity_ySlider.Limits = [-1 1];
            app.velocity_ySlider.ValueChangedFcn = createCallbackFcn(app, @velocity_ySliderValueChanged, true);
            app.velocity_ySlider.Value = -0.166666666666667;
            app.velocity_zSliderLabel = uilabel(app.UIFigure);
            app.velocity_zSliderLabel.Text = 'velocity_z';
            app.velocity_zSlider = uislider(app.UIFigure);
            app.velocity_zSlider.Limits = [-3 3];
            app.velocity_zSlider.ValueChangedFcn = createCallbackFcn(app, @velocity_zSliderValueChanged, true);
            app.velocity_zSlider.Value = 1;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'未求解'};
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CatchBall

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end